classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        yqdplotUIFigure  matlab.ui.Figure
        TabGroup         matlab.ui.container.TabGroup
        Tab              matlab.ui.container.Tab
        Button           matlab.ui.control.Button
        EditFieldLabel   matlab.ui.control.Label
        edt1             matlab.ui.control.NumericEditField
        func             matlab.ui.control.Button
        Panel            matlab.ui.container.Panel
        Button_3         matlab.ui.control.Button
        func_2           matlab.ui.control.Button
        func_3           matlab.ui.control.Button
        func_4           matlab.ui.control.Button
        RSliderLabel     matlab.ui.control.Label
        RSlider          matlab.ui.control.Slider
        GSliderLabel     matlab.ui.control.Label
        GSlider          matlab.ui.control.Slider
        BSliderLabel     matlab.ui.control.Label
        BSlider          matlab.ui.control.Slider
        EditField_3      matlab.ui.control.NumericEditField
        EditField_4      matlab.ui.control.NumericEditField
        EditField_5      matlab.ui.control.NumericEditField
        Button_4         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function getHandle(app, event)
            fignumber=get(app.edt1, 'Value');
            figure(fignumber);
        end

        % Button pushed function: func
        function magnify(app, event)
            fignumber=get(app.edt1, 'Value');
            figure(fignumber);
            obj = get(gca,'children');
            len = length(obj);
            pos_gca = get(gca, 'Position');
            gca_lim_x = get(gca,'XLim');
            gca_lim_y = get(gca,'YLim');
            scale_x = pos_gca(3) / (gca_lim_x(2)-gca_lim_x(1));
            scale_y = pos_gca(4) / (gca_lim_y(2)-gca_lim_y(1));
            [pos_x, pos_y]=ginput(2);
            pos_x = sort(pos_x);
            pos_y = sort(pos_y);
            rect_x = pos_gca(1) + scale_x * (pos_x(1)-gca_lim_x(1));
            rect_y = pos_gca(2) + scale_y * (pos_y(1)-gca_lim_y(1));
            rect_w = scale_x * (pos_x(2)-pos_x(1));
            rect_h = scale_y * (pos_y(2)-pos_y(1));
            anno_color = get(app.func_4, 'BackgroundColor');
            annotation('rectangle',[rect_x, rect_y, rect_w, rect_h], ...
                "EdgeColor",anno_color,"LineWidth",1.5,"LineStyle","-");
%             rectangle("Position",[pos_x(1), pos_y(1), pos_x(2)-pos_x(1), pos_y(2)-pos_y(1)],...
%                 "EdgeColor",'r',"LineWidth",1.5,"LineStyle","-");
            [place_x, place_y]=ginput(2);
            place_x = sort(place_x);
            place_y = sort(place_y);
            rect_x = pos_gca(1) + scale_x * (place_x(1)-gca_lim_x(1));
            rect_y = pos_gca(2) + scale_y * (place_y(1)-gca_lim_y(1));
            rect_w = scale_x * (place_x(2)-place_x(1));
            rect_h = scale_y * (place_y(2)-place_y(1));
            b = axes('Position',[rect_x, rect_y, rect_w, rect_h]);
            hold on;
            for i=1:len
                x = get(obj(i),'xData');
                y = get(obj(i),'yData');
                Color = get(obj(i),'Color');
        	LineStyle = get(obj(i),'LineStyle');
        	LineWidth = get(obj(i),'LineWidth');
        	Marker = get(obj(i),'Marker');
        	MarkerSize = get(obj(i),'MarkerSize');
                plot(b, x, y, 'LineStyle',LineStyle, ...
                           'LineWidth',LineWidth, ...
                           'Color',Color, ...
                           'Marker', Marker, ...
                           'MarkerSize', MarkerSize);
                xlim(pos_x);
                ylim(pos_y);
            end
            hold off;
            [pos_x, pos_y]=ginput(2);
            start_x = pos_gca(1) + scale_x * (pos_x(1)-gca_lim_x(1));
            start_y = pos_gca(2) + scale_y * (pos_y(1)-gca_lim_y(1));
            end_x = pos_gca(1) + scale_x * (pos_x(2)-gca_lim_x(1));
            end_y = pos_gca(2) + scale_y * (pos_y(2)-gca_lim_y(1));
            annotation('arrow',[start_x, end_x],[start_y, end_y], ...
                "Color",anno_color,"LineWidth",1.5,"LineStyle","-")
            
        end

        % Button pushed function: func_2
        function nextFig(app, event)
            fignumber=get(app.edt1, 'Value');
            fignumber=fignumber+1;
            set(app.edt1, 'Value', fignumber)
            figure(fignumber);
        end

        % Button pushed function: func_3
        function addarrow(app, event)
            fignumber=get(app.edt1, 'Value');
            figure(fignumber);
            pos_gca = get(gca, 'Position');
            gca_lim_x = get(gca,'XLim');
            gca_lim_y = get(gca,'YLim');
            scale_x = pos_gca(3) / (gca_lim_x(2)-gca_lim_x(1));
            scale_y = pos_gca(4) / (gca_lim_y(2)-gca_lim_y(1));
            [pos_x, pos_y]=ginput(2);
            start_x = pos_gca(1) + scale_x * (pos_x(1)-gca_lim_x(1));
            start_y = pos_gca(2) + scale_y * (pos_y(1)-gca_lim_y(1));
            end_x = pos_gca(1) + scale_x * (pos_x(2)-gca_lim_x(1));
            end_y = pos_gca(2) + scale_y * (pos_y(2)-gca_lim_y(1));
            anno_color = get(app.func_4, 'BackgroundColor');
            annotation('arrow',[start_x, end_x],[start_y, end_y], ...
                "Color",anno_color,"LineWidth",1.5,"LineStyle","-")
        end

        % Button pushed function: Button_3
        function open(app, event)
%             filetype = get(app.DropDown, 'Value');
%             filename = get(app.EditField_2, 'Value');
%             [filename,path] = uiputfile(['*.', filetype],filename);
%             set(app.EditField, 'Value', path);
%             set(app.EditField_2, 'Value', filename);
            fignumber=get(app.edt1, 'Value');
            figure(fignumber);
            [filename, pathname, fileindex]=uiputfile({'*.eps'; '*.png';'*.bmp';'*.jpg';},'图片保存为');
            if  filename~=0%未点“取消”按钮或未关闭
                file=strcat(pathname,filename);
                switch fileindex %根据不同的选择保存为不同的类型        
                    case 1
                        print(gcf,'-dpng',file);% print(new_f_handle,'-dpng',filename);效果一样，将图像打印到指定文件中
%                         fprintf('>>已保存到：%s\n',file);
                    case 2
                        print(gcf,'-dbmp',file);
%                         fprintf('>>已保存到：%s\n',file);
                    case 3
                        print(gcf,'-djpg',file);
%                         fprintf('>>已保存到：%s\n',file);
                    case 4
                        print(gcf,'-depsc',file);
%                         fprintf('>>已保存到：%s\n',file);
                end 
            end
%             print 2.eps -depsc2
%             set(gcf,'color','white','paperpositionmode','auto');
%             saveas(gcf,[path, filename], 'psc2');
        end

        % Value changing function: RSlider
        function r_changing(app, event)
            changingValue = event.Value;
            set(app.EditField_3, 'Value', changingValue);
            r = get(app.EditField_3, 'Value') / 255;
            g = get(app.EditField_4, 'Value') / 255;
            b = get(app.EditField_5, 'Value') / 255;
            set(app.func_4, 'BackgroundColor', [r, g, b]);
        end

        % Value changed function: EditField_3
        function setR(app, event)
            value = app.EditField_3.Value;
            set(app.RSlider, 'Value', value)
            r = get(app.EditField_3, 'Value') / 255;
            g = get(app.EditField_4, 'Value') / 255;
            b = get(app.EditField_5, 'Value') / 255;
            set(app.func_4, 'BackgroundColor', [r, g, b]);
        end

        % Value changing function: GSlider
        function g_changing(app, event)
            changingValue = event.Value;
            set(app.EditField_4, 'Value', changingValue);
            r = get(app.EditField_3, 'Value') / 255;
            g = get(app.EditField_4, 'Value') / 255;
            b = get(app.EditField_5, 'Value') / 255;
            set(app.func_4, 'BackgroundColor', [r, g, b]);
        end

        % Value changed function: EditField_4
        function setG(app, event)
            value = app.EditField_4.Value;
            set(app.GSlider, 'Value', value)
            r = get(app.EditField_3, 'Value') / 255;
            g = get(app.EditField_4, 'Value') / 255;
            b = get(app.EditField_5, 'Value') / 255;
            set(app.func_4, 'BackgroundColor', [r, g, b]);
            
        end

        % Value changing function: BSlider
        function b_changing(app, event)
            changingValue = event.Value;
            set(app.EditField_5, 'Value', changingValue);
            r = get(app.EditField_3, 'Value') / 255;
            g = get(app.EditField_4, 'Value') / 255;
            b = get(app.EditField_5, 'Value') / 255;
            set(app.func_4, 'BackgroundColor', [r, g, b]);
            
        end

        % Value changed function: EditField_5
        function setB(app, event)
            value = app.EditField_5.Value;
            set(app.BSlider, 'Value', value)
            r = get(app.EditField_3, 'Value') / 255;
            g = get(app.EditField_4, 'Value') / 255;
            b = get(app.EditField_5, 'Value') / 255;
            set(app.func_4, 'BackgroundColor', [r, g, b]);
            
        end

        % Button pushed function: Button_4
        function close_one(app, event)
            fignumber=get(app.edt1, 'Value');
            close(figure(fignumber))
        end

        % Button pushed function: Button_5
        function close_all(app, event)
            close all
        end

        % Callback function
        function surprise(app, event)
            msgbox('还没做',"modal")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.yqdplotUIFigure = uifigure('Visible', 'off');
            app.yqdplotUIFigure.Name = 'yqdplot';
            app.TabGroup = uitabgroup(app.yqdplotUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '局部放大图设置';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @getHandle, true);
            app.Button.Text = '确认窗口句柄';
            app.EditFieldLabel = uilabel(app.Tab);
            app.EditFieldLabel.Text = '激活窗口序号';
            app.edt1 = uieditfield(app.Tab, 'numeric');
            app.edt1.Limits = [1 Inf];
            app.edt1.RoundFractionalValues = 'on';
            app.edt1.Value = 1;
            app.func = uibutton(app.Tab, 'push');
            app.func.ButtonPushedFcn = createCallbackFcn(app, @magnify, true);
            app.func.Text = '局部放大图配置';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '输出配置';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @open, true);
            app.Button_3.Text = '保存';
            app.func_2 = uibutton(app.Tab, 'push');
            app.func_2.ButtonPushedFcn = createCallbackFcn(app, @nextFig, true);
            app.func_2.Text = '下一张图';
            app.func_3 = uibutton(app.Tab, 'push');
            app.func_3.ButtonPushedFcn = createCallbackFcn(app, @addarrow, true);
            app.func_3.Text = '添加箭头';
            app.func_4 = uibutton(app.Tab, 'push');
            app.func_4.Text = '标注颜色';
            app.RSliderLabel = uilabel(app.Tab);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.Tab);
            app.RSlider.Limits = [0 255];
            app.RSlider.ValueChangingFcn = createCallbackFcn(app, @r_changing, true);
            app.RSlider.Value = 255;
            app.GSliderLabel = uilabel(app.Tab);
            app.GSliderLabel.Text = 'G';
            app.GSlider = uislider(app.Tab);
            app.GSlider.Limits = [0 255];
            app.GSlider.ValueChangingFcn = createCallbackFcn(app, @g_changing, true);
            app.GSlider.Value = 59;
            app.BSliderLabel = uilabel(app.Tab);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.Tab);
            app.BSlider.Limits = [0 255];
            app.BSlider.ValueChangingFcn = createCallbackFcn(app, @b_changing, true);
            app.BSlider.Value = 59;
            app.EditField_3 = uieditfield(app.Tab, 'numeric');
            app.EditField_3.Limits = [0 255];
            app.EditField_3.RoundFractionalValues = 'on';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @setR, true);
            app.EditField_3.Value = 255;
            app.EditField_4 = uieditfield(app.Tab, 'numeric');
            app.EditField_4.Limits = [0 255];
            app.EditField_4.RoundFractionalValues = 'on';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @setG, true);
            app.EditField_4.Value = 59;
            app.EditField_5 = uieditfield(app.Tab, 'numeric');
            app.EditField_5.Limits = [0 255];
            app.EditField_5.RoundFractionalValues = 'on';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @setB, true);
            app.EditField_5.Value = 59;
            app.Button_4 = uibutton(app.Tab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @close_one, true);
            app.Button_4.Text = '关闭指定序号窗口';
            app.Button_5 = uibutton(app.Tab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @close_all, true);
            app.Button_5.Text = '关闭所有绘图窗口';
            % Show the figure after all components are created
            app.yqdplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.yqdplotUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.yqdplotUIFigure)
        end
    end
end